classdef Final_Person_Identification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PersonIdentificationUIFigure  matlab.ui.Figure
        FaceImage                     matlab.ui.control.Image
        AuthorisedLampLabel           matlab.ui.control.Label
        AuthorisedLamp                matlab.ui.control.Lamp
        CaptureAudioButton            matlab.ui.control.Button
        VerifyButton                  matlab.ui.control.Button
        VoiceImage                    matlab.ui.control.Image
        LoadFaceButton                matlab.ui.control.Button
        LoadVoiceButton               matlab.ui.control.Button
        CaptureFaceButton             matlab.ui.control.Button
        FaceClassificationEditFieldLabel  matlab.ui.control.Label
        FaceClassificationEditField   matlab.ui.control.EditField
        VoiceClassificationEditFieldLabel  matlab.ui.control.Label
        VoiceClassificationEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        authPath % Path of the image that needs to be authorised
        MELPath % Path of the MEL spectrogram that needs to be authorised
        faceModel % Variable of face identification model
        voiceModel % Variable of voice identification model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('faceModel.mat', 'net');
            app.faceModel = net;
            load('voiceModel.mat', 'netTransfer');
            app.voiceModel = netTransfer;
        end

        % Callback function: FaceImage, LoadFaceButton
        function FaceImageClicked(app, event)
            [file, path] = uigetfile("*.jpg");
            app.authPath = [path, file];
            app.FaceImage.ImageSource = imread(app.authPath);
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            imagePath = app.authPath;
            authImageIMDS = imageDatastore(imagePath);
            [classification, scores] = classify(app.faceModel, authImageIMDS);
            faceConfidence = max(scores);
            
            if strcmp(char(classification), 'facesAndrew')
                app.FaceClassificationEditField.Value = 'Andrew';
            elseif strcmp(char(classification), 'facesAsh')
                app.FaceClassificationEditField.Value = 'Ash';
            elseif strcmp(char(classification), 'facesAshwin')
                app.FaceClassificationEditField.Value = 'Ashwin';
            elseif strcmp(char(classification), 'facesRory')
                app.FaceClassificationEditField.Value = 'Rory';
            elseif strcmp(char(classification), 'unknownFaces')
                app.FaceClassificationEditField.Value = 'Unknown Face';
            end
            
            test = imread(app.MELPath);
            test = imresize(test, [227 227]);
            
            [Y, prediction] = classify(app.voiceModel, test);
            voiceConfidence = max(prediction);
            
            if strcmp(char(Y), 'UnknownVoices')
                app.VoiceClassificationEditField.Value = 'Unknown Voice';
            else
                app.VoiceClassificationEditField.Value = char(Y);
            end
            
            if strcmp(char(Y), 'Andrew') && strcmp(char(classification), 'facesAndrew') || ...
                    strcmp(char(Y), 'Ash') && strcmp(char(classification), 'facesAsh') || ...
                    strcmp(char(Y), 'Ashwin') && strcmp(char(classification), 'facesAshwin') || ...
                    strcmp(char(Y), 'Rory') && strcmp(char(classification), 'facesRory')
                finalConfidence = ((sqrt(faceConfidence) + sqrt(voiceConfidence))/2)^2;
                
                if finalConfidence >= 0.6
                    app.AuthorisedLamp.Color = 'g';
                else
                    app.AuthorisedLamp.Color = 'r';
                end
            else
                app.AuthorisedLamp.Color = 'r';
            end
        end

        % Callback function: LoadVoiceButton, VoiceImage
        function LoadVoiceButtonPushed(app, event)
            [file, path] = uigetfile({'*.flac; *.mp4; *.m4a'});
            convertSingleAudioToMelSpec([path, file], 'GUI');
            app.MELPath = './Testing/GUI.png';
            app.VoiceImage.ImageSource = imread(app.MELPath);
        end

        % Button pushed function: CaptureFaceButton
        function CaptureFaceButtonPushed(app, event)
            % For hardware
        end

        % Button pushed function: CaptureAudioButton
        function CaptureAudioButtonPushed(app, event)
            % For hardware
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PersonIdentificationUIFigure = uifigure('Visible', 'off');
            app.PersonIdentificationUIFigure.Name = 'Person Identification';
            app.FaceImage = uiimage(app.PersonIdentificationUIFigure);
            app.FaceImage.ImageClickedFcn = createCallbackFcn(app, @FaceImageClicked, true);
            app.AuthorisedLampLabel = uilabel(app.PersonIdentificationUIFigure);
            app.AuthorisedLampLabel.Text = 'Authorised';
            app.AuthorisedLamp = uilamp(app.PersonIdentificationUIFigure);
            app.CaptureAudioButton = uibutton(app.PersonIdentificationUIFigure, 'push');
            app.CaptureAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureAudioButtonPushed, true);
            app.CaptureAudioButton.Text = 'Capture Audio';
            app.VerifyButton = uibutton(app.PersonIdentificationUIFigure, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.VoiceImage = uiimage(app.PersonIdentificationUIFigure);
            app.VoiceImage.ImageClickedFcn = createCallbackFcn(app, @LoadVoiceButtonPushed, true);
            app.LoadFaceButton = uibutton(app.PersonIdentificationUIFigure, 'push');
            app.LoadFaceButton.ButtonPushedFcn = createCallbackFcn(app, @FaceImageClicked, true);
            app.LoadFaceButton.Text = 'Load Face';
            app.LoadVoiceButton = uibutton(app.PersonIdentificationUIFigure, 'push');
            app.LoadVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVoiceButtonPushed, true);
            app.LoadVoiceButton.Text = 'Load Voice';
            app.CaptureFaceButton = uibutton(app.PersonIdentificationUIFigure, 'push');
            app.CaptureFaceButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureFaceButtonPushed, true);
            app.CaptureFaceButton.Text = 'Capture Face';
            app.FaceClassificationEditFieldLabel = uilabel(app.PersonIdentificationUIFigure);
            app.FaceClassificationEditFieldLabel.Text = 'Face Classification';
            app.FaceClassificationEditField = uieditfield(app.PersonIdentificationUIFigure, 'text');
            app.FaceClassificationEditField.Editable = 'off';
            app.VoiceClassificationEditFieldLabel = uilabel(app.PersonIdentificationUIFigure);
            app.VoiceClassificationEditFieldLabel.Text = 'Voice Classification';
            app.VoiceClassificationEditField = uieditfield(app.PersonIdentificationUIFigure, 'text');
            app.VoiceClassificationEditField.Editable = 'off';
            % Show the figure after all components are created
            app.PersonIdentificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Person_Identification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PersonIdentificationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PersonIdentificationUIFigure)
        end
    end
end