classdef Person_Identification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AuthoriseMeButton             matlab.ui.control.Button
        SuccessfulFaceAuthorisationEditFieldLabel  matlab.ui.control.Label
        SuccessfulFaceAuthorisationEditField  matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
        SelectImageButton             matlab.ui.control.Button
        TrainModelButton              matlab.ui.control.Button
        ModelTrainedLabel             matlab.ui.control.Label
        FaceClassificationEditFieldLabel  matlab.ui.control.Label
        FaceClassificationEditField   matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        SelectAudioButton             matlab.ui.control.Button
        VoiceClassificationEditFieldLabel  matlab.ui.control.Label
        VoiceClassificationEditField  matlab.ui.control.EditField
        SuccessfulVoiceAuthorisationEditFieldLabel  matlab.ui.control.Label
        SuccessfulVoiceAuthorisationEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        authPath % Path of the image that needs to be authorised
        MELPath % Path of the MEL spectrogram that needs to be authorised
        network % CNN Trained
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Image, SelectImageButton
        function SelectImageButtonPushed(app, event)
           
            
            [file,path] = uigetfile("*.jpg");
            app.authPath = strcat(path,"\",file);
            disp(file)
            disp(path)
            app.Image.ImageSource = imread(strcat(path,"\",file));
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)


            % Source Face Recognition Data
            digitDatasetPath = fullfile('C:\Users\goode\Downloads\Face-Images\Face Images\OurFaces');
            % Image Data Store for Face Recognition Data
            imds = imageDatastore(digitDatasetPath, ...
                'IncludeSubfolders',true, ...
                'LabelSource','foldernames');
            
            numTrainFiles = 50;
            [imdsTrain,imdsValidation] = splitEachLabel(imds,numTrainFiles,'randomize');


            inputSize = [200 200 3];
            numClasses = 5;
            layers = [
                imageInputLayer(inputSize)
                convolution2dLayer(200,120)
                batchNormalizationLayer
                reluLayer
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];


            options = trainingOptions('sgdm', ...
            'MaxEpochs',64, ...
            'ValidationData',imdsValidation, ...
            'ValidationFrequency',30, ...
            'Verbose',false, ...
            'Plots','none');
             

            app.network = trainNetwork(imdsTrain,layers,options);
            app.ModelTrainedLabel.BackgroundColor = [0.39,0.95,0.29];


        end

        % Button pushed function: AuthoriseMeButton
        function AuthoriseMeButtonPushed(app, event)
    
            imagePath = app.authPath;
            authImageIMDS = imageDatastore(imagePath);
            [classification, scores] = classify(app.network, authImageIMDS);
            maximumScore = max(scores);
            disp(class(maximumScore))
            disp(classification)
            app.SuccessfulFaceAuthorisationEditField.Value = double(maximumScore);
            app.FaceClassificationEditField.Value = char(classification);
            
            load('voiceModel.mat', 'netTransfer');
            test = imread(convertSingleAudioToMelSpec(app.MELPath, 'GUI'));
            test = imresize(test, [227 227]);
            
            [Y, prediction] = classify(netTransfer, test);
            app.SuccessfulVoiceAuthorisationEditField.Value = double(prediction);
            app.VoiceClassificationEditField.Value = char(Y);
        end

        % Button pushed function: SelectAudioButton
        function SelectAudioButtonPushed(app, event)
            [file, path] = uigetfile({'*.flac'; '*.mp4'; '*.m4a'});
            convertAudioToMelSpec(path, file);
            app.MELPath = ['./mel_spectrograms/', file];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AuthoriseMeButton = uibutton(app.UIFigure, 'push');
            app.AuthoriseMeButton.ButtonPushedFcn = createCallbackFcn(app, @AuthoriseMeButtonPushed, true);
            app.AuthoriseMeButton.Text = 'Authorise Me';
            app.SuccessfulFaceAuthorisationEditFieldLabel = uilabel(app.UIFigure);
            app.SuccessfulFaceAuthorisationEditFieldLabel.Text = 'Successful Face Authorisation %';
            app.SuccessfulFaceAuthorisationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuccessfulFaceAuthorisationEditField.Editable = 'off';
            app.SuccessfulFaceAuthorisationEditField.Value = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.TrainModelButton = uibutton(app.UIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.ModelTrainedLabel = uilabel(app.UIFigure);
            app.ModelTrainedLabel.Text = 'Model Trained';
            app.FaceClassificationEditFieldLabel = uilabel(app.UIFigure);
            app.FaceClassificationEditFieldLabel.Text = 'Face Classification';
            app.FaceClassificationEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SelectAudioButton = uibutton(app.UIFigure, 'push');
            app.SelectAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioButtonPushed, true);
            app.SelectAudioButton.Text = 'Select Audio';
            app.VoiceClassificationEditFieldLabel = uilabel(app.UIFigure);
            app.VoiceClassificationEditFieldLabel.Text = 'Voice Classification';
            app.VoiceClassificationEditField = uieditfield(app.UIFigure, 'text');
            app.SuccessfulVoiceAuthorisationEditFieldLabel = uilabel(app.UIFigure);
            app.SuccessfulVoiceAuthorisationEditFieldLabel.Text = 'Successful Voice Authorisation %';
            app.SuccessfulVoiceAuthorisationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuccessfulVoiceAuthorisationEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Person_Identification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end